classdef rocket < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        LengthEditField               matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
        RocketsInnerradiusEditField   matlab.ui.control.NumericEditField
        InnerRadiusLabel              matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        LaunchButton                  matlab.ui.control.Button
        PropellantdensityEditField    matlab.ui.control.NumericEditField
        RokcetsradiusLabel            matlab.ui.control.Label
        RocketsradiusEditField        matlab.ui.control.NumericEditField
        RokcetsradiusEditFieldLabel   matlab.ui.control.Label
        C_DEditField                  matlab.ui.control.NumericEditField
        C_DEditFieldLabel             matlab.ui.control.Label
        V_fuelEditField               matlab.ui.control.NumericEditField
        V_fuelEditFieldLabel          matlab.ui.control.Label
        MassflowrateEditField         matlab.ui.control.NumericEditField
        MassflowrateEditFieldLabel    matlab.ui.control.Label
        RocketmassEditField           matlab.ui.control.NumericEditField
        RocketmassEditFieldLabel      matlab.ui.control.Label
        PropellantmassEditField       matlab.ui.control.NumericEditField
        PropellantmassEditFieldLabel  matlab.ui.control.Label
        V                             matlab.ui.control.UIAxes
        h                             matlab.ui.control.UIAxes
        F_n                           matlab.ui.control.UIAxes
        density                       matlab.ui.control.UIAxes
        F_DAxis                       matlab.ui.control.UIAxes
        Tab2                          matlab.ui.container.Tab
        LaunchButton_2                matlab.ui.control.Button
        velocityEditField_2           matlab.ui.control.NumericEditField
        velocityEditField_2Label      matlab.ui.control.Label
        altitudeEditField_2           matlab.ui.control.NumericEditField
        altitudeEditField_2Label      matlab.ui.control.Label
        velocityEditField             matlab.ui.control.NumericEditField
        velocityEditFieldLabel        matlab.ui.control.Label
        altitudeEditField             matlab.ui.control.NumericEditField
        altitudeEditFieldLabel        matlab.ui.control.Label
        dragly                        matlab.ui.control.UIAxes
        dragless                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xx=[0 1 2 3 4 5 6 7 8 9 10 11 13 15 17 20 25 30 32 35 40 45 47 50 51 60 70 71 80 85 90 100 105 110];
        p=[1.35 1.22 1.11 1 0.9 0.82 0.74 0.66 0.59 0.52 0.47 0.41 0.36 0.26 0.19 0.14 0.08 0.04 0.02 0.01 0.008 0.004 0.002 0.0014 0.001 0.00086 0.0003 0.00007 0.000015 0.000007 0.000003 0.0000005 0.0000002 0.0000001];


        m=6;
        M=1;
        M_i=0;
        k=0.05;
        V_fuel=3500;
        C_D=0.6;
        r=0.06;
        i_r=0.02;
        d=1670;
        n=8000;
        g=9.81;

        T_i
        N
        t_e
        length;
        a;

        p2;
        x
        y2
        F_D=[];

        t
        VV=[];
        VVVV=[0];
        hh=[];
        hhhh=[10000];
        V_e_t=[];
        Mg_t=[];
        F_net=[0];
    end
    
    methods (Access = private)
        
        function calculate(app)
            for T=1:app.n-1
                ro=polyval(app.p2,app.hhhh(T)/1000);
                if ro<0
                    ro=0.0000001;
                end

                k1=app.dif(app.t(T),app.VVVV(T),ro);
                l1=app.VVVV(T);
                k2=app.dif(app.t(T)+app.a/2,app.VVVV(T)+app.a*k1/2,ro);
                l2=app.VVVV(T)+app.a*l1/2;
                k3=app.dif(app.t(T)+app.a/2,app.VVVV(T)+app.a*k2/2,ro);
                l3=app.VVVV(T)+app.a*l2/2;
                k4=app.dif(app.t(T)+app.a,app.VVVV(T)+app.a*k3,ro);
                l4=app.VVVV(T)+app.a*l3;
                app.VVVV(T+1)=app.VVVV(T)+app.a/6*(k1+2*k2+2*k3+k4);
                app.hhhh(T+1)=app.hhhh(T)+app.a/6*(l1+2*l2+2*l3+l4);
                
            end
        end
        function [f] =dif(app,T,V,ro)
            f=((app.V_fuel+V)*app.k-(app.M_i-app.k*T)*app.g-1/2*app.C_D*ro*app.r^2*pi*V^2)/(app.M_i-app.k*T);
        end 
        function calculate2(app)
            for T=1:app.n
                app.VV(T)=((app.k*(app.V_fuel+app.t(T)/2*app.g)-app.M_i*app.g)/(app.M_i-app.k*app.t(T)))*app.t(T);
                %disp("V= "+T+" "+V(T)+" "+F_D(T));
                app.hh(T)=log(app.M_i/(app.M_i-app.k*app.t(T)))*app.M_i/app.k*(app.V_fuel-app.M_i*app.g/(2*app.k))-app.t(T)*(app.V_fuel-app.M_i*app.g/(2*app.k)-app.g*app.t(T)/4);

                %disp("h= "+T+" "+h(T));
                %h_v(T)=(log((V_fuel+V(T))/V_fuel)+V_fuel/(V_fuel+V(T))-1)*V_fuel*M_i/k;
                app.V_e_t(T)=sqrt(2*6.67*10^13*5.97/(6378000+app.hh(T)))/1000;
                app.Mg_t(T)=(app.M_i-app.k*app.t(T))*app.g;
                ro=polyval(app.p2,app.hhhh(T)/1000);
                if ro<0
                    ro=0;
                end
                app.F_D(T)=1/2*app.C_D*ro*app.VVVV(T)^2*app.r^2*pi;
                app.F_net(T)=app.k*app.V_fuel-(app.M_i-app.k*app.t(T))*app.g-1/2*app.C_D*ro*app.r^2*pi*app.VVVV(T)^2;
            end
            %disp(size(app.F_net));
            app.draw_rocket();
        end
        function set(app)
            app.m=app.PropellantmassEditField.Value;
            app.k=app.MassflowrateEditField.Value;
            app.V_fuel=app.V_fuelEditField.Value;
            app.C_D=app.C_DEditField.Value;
            app.r=app.RocketsradiusEditField.Value;
            app.i_r=app.RocketsInnerradiusEditField.Value;
            app.d=app.PropellantdensityEditField.Value;
            app.n=app.nEditField.Value;

            app.M_i=app.m+app.M;
            app.T_i=app.k*app.V_fuel;
            app.N=app.M_i*app.g;
            app.t_e=app.m/app.k;
            app.length=app.m/(app.r^2*pi*app.d-app.i_r^2*app.d);
            app.LengthEditField.Value=app.length;
            app.a=app.t_e/app.n;
            app.M=app.length*0.006*2*pi*app.r*2710+1.5;
            app.RocketmassEditField.Value=app.M;
    
            app.p2=polyfit(app.xx,app.p,8);
            app.x=linspace(1,100,app.n);
            app.y2=polyval(app.p2,app.x);

            app.t=linspace(1,app.t_e,app.n);
            app.VV=[];
            app.VVVV=[0];
            app.hh=[];
            app.hhhh=[10000];
            app.V_e_t=[];
            app.Mg_t=[];
        end
        function graph(app)
            plot(app.V,app.t,app.VV/1000,"r-");
            hold(app.V,"on");
            plot(app.V,app.t,app.VVVV/1000,"b-")

            plot(app.h,app.t,app.hh/1000,"r-");
            hold(app.h,"on");
            plot(app.h,app.t,app.hhhh/1000,"b-")

            plot(app.F_DAxis,app.t,app.F_D,"r-");
            hold(app.F_DAxis,"on");
            plot(app.F_DAxis,app.t,app.T_i*ones(app.n,1),"b-")

            plot(app.density,app.x,app.y2,"b-");

            plot(app.F_n,app.t,app.F_net,"b-");

            hold(app.V,"off");
            hold(app.h,"off");
            hold(app.F_DAxis,"off");
            hold(app.density,"off");
            hold(app.F_n,"off");
            %https://ntrs.nasa.gov/api/citations/20190001326/downloads/20190001326.pdf
        end
        function draw_rocket(app)
            maxx=max(app.hh)*1.8/1000;
            ylim(app.dragless,[-maxx/10 maxx-maxx/10]);
            ylim(app.dragly,[-maxx/10 maxx-maxx/10]);

            xlim(app.dragless,[0 maxx]);
            xlim(app.dragly,[0 maxx]);
            app.circle(app.dragless,maxx/2,-maxx*1.4,maxx*1.4);
            app.platform(app.dragly);
            app.circle(app.dragly,maxx/2,-maxx*1.4,maxx*1.4);
            app.chicken_jockey(app.dragless,maxx/2,0,10,30);
            app.chicken_jockey(app.dragly,maxx/2,0,10,30);
        end
        function move(app)
            maxx=max(app.hh)*1.8/1000;
            for n=1:size(app.hh,2)/50:size(app.hh,2)
                app.circle(app.dragless,maxx/2,-maxx*1.4,maxx*1.4);
                app.chicken_jockey(app.dragless,maxx/2,app.hh(n)/1000,10,30);
                app.altitudeEditField.Value=app.hh(n)/1000;
                app.velocityEditField.Value=app.VV(n)/1000;

                app.circle(app.dragly,maxx/2,-maxx*1.4,maxx*1.4);
                app.platform(app.dragly);
                app.chicken_jockey(app.dragly,maxx/2,app.hhhh(n)/1000,10,30);
                app.altitudeEditField_2.Value=app.hhhh(n)/1000;
                app.velocityEditField_2.Value=app.VVVV(n)/1000;
                drawnow;
            end
        end
        function circle(app,p,x,y,r)
            clc;
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            plot(p,xunit, yunit);
            hold(p,"on");
        end
        function chicken_jockey(app,p,x,y,w,l)
            plot(p,[x-w/2,x+w/2],[y,y],"r-");
            plot(p,[x-w/2,x-w/2],[y,y+l],"r-");
            plot(p,[x+w/2,x+w/2],[y,y+l],"r-");
            plot(p,[x-w/2,x+w/2],[y+l,y+l],"r-");
            plot(p,[x-w/2,x],[y+l,y+l*1.5],"r-");
            plot(p,[x+w/2,x],[y+l,y+l*1.5],"r-");
            hold(p,"off");
        end
        function platform(app,p)
            maxx=max(app.hh)*1.8/1000;
            plot(p,[maxx/2-50,maxx/2+50],[10,10],"g-");
            plot(p,[maxx/2-50,maxx/2-50],[10,40],"g-");
            plot(p,[maxx/2+50,maxx/2+50],[10,40],"g-");
            app.circle(p,maxx/2-50,60,20);
            app.circle(p,maxx/2+50,60,20);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.set();
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            app.set();
            app.calculate();
            app.calculate2();
            app.graph();
        end

        % Value changed function: RocketsradiusEditField
        function RocketsradiusEditFieldValueChanged(app, event)
            value = app.RocketsradiusEditField.Value;
            app.set();     
        end

        % Value changed function: RocketsInnerradiusEditField
        function RocketsInnerradiusEditFieldValueChanged(app, event)
            value = app.RocketsInnerradiusEditField.Value;
            app.set();
        end

        % Value changed function: PropellantdensityEditField
        function PropellantdensityEditFieldValueChanged(app, event)
            value = app.PropellantdensityEditField.Value;
            app.set();
        end

        % Button pushed function: LaunchButton_2
        function LaunchButton_2Pushed(app, event)
            app.move();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.F_DAxis = uiaxes(app.GridLayout);
            title(app.F_DAxis, 'F_D vs t')
            xlabel(app.F_DAxis, 't(s)')
            ylabel(app.F_DAxis, 'Force(N)')
            zlabel(app.F_DAxis, 'Z')
            app.density = uiaxes(app.GridLayout);
            title(app.density, 'density vs h')
            xlabel(app.density, 'h(km)')
            ylabel(app.density, 'denisty(kg/m^3)')
            zlabel(app.density, 'Z')
            app.F_n = uiaxes(app.GridLayout);
            title(app.F_n, 'F_n vs t')
            xlabel(app.F_n, 't(s)')
            ylabel(app.F_n, 'F_n(N)')
            zlabel(app.F_n, 'Z')
            app.h = uiaxes(app.GridLayout);
            title(app.h, 'h vs t')
            xlabel(app.h, 't(s)')
            ylabel(app.h, 'h(km)')
            zlabel(app.h, 'Z')
            app.V = uiaxes(app.GridLayout);
            title(app.V, 'V vs t')
            xlabel(app.V, 't(s)')
            ylabel(app.V, 'V(km/s)')
            zlabel(app.V, 'Z')
            app.PropellantmassEditFieldLabel = uilabel(app.GridLayout);
            app.PropellantmassEditFieldLabel.Text = 'Propellant mass';
            app.PropellantmassEditField = uieditfield(app.GridLayout, 'numeric');
            app.PropellantmassEditField.Value = 8;
            app.RocketmassEditFieldLabel = uilabel(app.GridLayout);
            app.RocketmassEditFieldLabel.Text = 'Rocket mass';
            app.RocketmassEditField = uieditfield(app.GridLayout, 'numeric');
            app.RocketmassEditField.Editable = 'off';
            app.RocketmassEditField.Value = 1;
            app.MassflowrateEditFieldLabel = uilabel(app.GridLayout);
            app.MassflowrateEditFieldLabel.Text = 'Mass flow rate';
            app.MassflowrateEditField = uieditfield(app.GridLayout, 'numeric');
            app.MassflowrateEditField.Value = 0.05;
            app.V_fuelEditFieldLabel = uilabel(app.GridLayout);
            app.V_fuelEditFieldLabel.Text = 'V_fuel';
            app.V_fuelEditField = uieditfield(app.GridLayout, 'numeric');
            app.V_fuelEditField.Value = 3500;
            app.C_DEditFieldLabel = uilabel(app.GridLayout);
            app.C_DEditFieldLabel.Text = 'C_D';
            app.C_DEditField = uieditfield(app.GridLayout, 'numeric');
            app.C_DEditField.Value = 0.6;
            app.RokcetsradiusEditFieldLabel = uilabel(app.GridLayout);
            app.RokcetsradiusEditFieldLabel.Text = 'Rokcet''s radius';
            app.RocketsradiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.RocketsradiusEditField.ValueChangedFcn = createCallbackFcn(app, @RocketsradiusEditFieldValueChanged, true);
            app.RocketsradiusEditField.Value = 0.06;
            app.RokcetsradiusLabel = uilabel(app.GridLayout);
            app.RokcetsradiusLabel.Text = '''Propellant density''';
            app.PropellantdensityEditField = uieditfield(app.GridLayout, 'numeric');
            app.PropellantdensityEditField.ValueChangedFcn = createCallbackFcn(app, @PropellantdensityEditFieldValueChanged, true);
            app.PropellantdensityEditField.Value = 1670;
            app.LaunchButton = uibutton(app.GridLayout, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.nEditFieldLabel = uilabel(app.GridLayout);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout, 'numeric');
            app.nEditField.Value = 8000;
            app.InnerRadiusLabel = uilabel(app.GridLayout);
            app.InnerRadiusLabel.Text = 'Inner Radius';
            app.RocketsInnerradiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.RocketsInnerradiusEditField.ValueChangedFcn = createCallbackFcn(app, @RocketsInnerradiusEditFieldValueChanged, true);
            app.RocketsInnerradiusEditField.Value = 0.04;
            app.LengthEditFieldLabel = uilabel(app.GridLayout);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.LengthEditField.Editable = 'off';
            app.LengthEditField.Value = 1670;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.dragless = uiaxes(app.Tab2);
            title(app.dragless, 'without drag')
            xlabel(app.dragless, 'X')
            ylabel(app.dragless, 'Y')
            zlabel(app.dragless, 'Z')
            app.dragly = uiaxes(app.Tab2);
            title(app.dragly, 'with drag')
            xlabel(app.dragly, 'X')
            ylabel(app.dragly, 'Y')
            zlabel(app.dragly, 'Z')
            app.altitudeEditFieldLabel = uilabel(app.Tab2);
            app.altitudeEditFieldLabel.Text = 'altitude';
            app.altitudeEditField = uieditfield(app.Tab2, 'numeric');
            app.altitudeEditField.Editable = 'off';
            app.velocityEditFieldLabel = uilabel(app.Tab2);
            app.velocityEditFieldLabel.Text = 'velocity';
            app.velocityEditField = uieditfield(app.Tab2, 'numeric');
            app.velocityEditField.Editable = 'off';
            app.altitudeEditField_2Label = uilabel(app.Tab2);
            app.altitudeEditField_2Label.Text = 'altitude';
            app.altitudeEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.altitudeEditField_2.Editable = 'off';
            app.velocityEditField_2Label = uilabel(app.Tab2);
            app.velocityEditField_2Label.Text = 'velocity';
            app.velocityEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.velocityEditField_2.Editable = 'off';
            app.LaunchButton_2 = uibutton(app.Tab2, 'push');
            app.LaunchButton_2.ButtonPushedFcn = createCallbackFcn(app, @LaunchButton_2Pushed, true);
            app.LaunchButton_2.Text = 'Launch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rocket

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end